classdef PatchlinerAuto < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PatchlinerAutoUIFigure          matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        AnalysisConfigurationTab        matlab.ui.container.Tab
        ImportDataPanel                 matlab.ui.container.Panel
        DataFilerequiredLabel           matlab.ui.control.Label
        BrowseDataFile                  matlab.ui.control.Button
        DataFileLabel                   matlab.ui.control.Label
        FilenameLabel                   matlab.ui.control.Label
        FileLocationLabel               matlab.ui.control.Label
        DataPathLabel                   matlab.ui.control.Label
        VoltageParametersRequiredPanel  matlab.ui.container.Panel
        InitialEditFieldLabel           matlab.ui.control.Label
        InitialEditField                matlab.ui.control.NumericEditField
        EndEditFieldLabel               matlab.ui.control.Label
        EndEditField                    matlab.ui.control.NumericEditField
        NumberofvoltagestepsLabel       matlab.ui.control.Label
        StepsEditField                  matlab.ui.control.NumericEditField
        sweepspercellLabel              matlab.ui.control.Label
        ParametersPanel_1               matlab.ui.container.Panel
        CurrentScaleDropDownLabel       matlab.ui.control.Label
        CurrentScaleDropDown            matlab.ui.control.DropDown
        CalculateConductanceCheckBox    matlab.ui.control.CheckBox
        eKmVLabel                       matlab.ui.control.Label
        eKmVEditField                   matlab.ui.control.NumericEditField
        ParametersPanel_2               matlab.ui.container.Panel
        RiseTimePercentagesLabel        matlab.ui.control.Label
        SubLabel                        matlab.ui.control.Label
        SubEditField                    matlab.ui.control.NumericEditField
        SupLabel                        matlab.ui.control.Label
        SupEditField                    matlab.ui.control.NumericEditField
        LoadSettingsPanel               matlab.ui.container.Panel
        LoadSettingsFile                matlab.ui.control.Button
        SettingsFileLabel               matlab.ui.control.Label
        CurrentSettingsFileLabel        matlab.ui.control.Label
        ImportDataButton                matlab.ui.control.StateButton
        WellsTab                        matlab.ui.container.Tab
        WellsPanel                      matlab.ui.container.Panel
        Well_1Button                    matlab.ui.control.StateButton
        Well_2Button                    matlab.ui.control.StateButton
        Well_3Button                    matlab.ui.control.StateButton
        Well_4Button                    matlab.ui.control.StateButton
        Well_5Button                    matlab.ui.control.StateButton
        Well_6Button                    matlab.ui.control.StateButton
        Well_7Button                    matlab.ui.control.StateButton
        Well_8Button                    matlab.ui.control.StateButton
        ClearAllButton                  matlab.ui.control.Button
        SelectAllButton                 matlab.ui.control.Button
        GraphTab                        matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        TimeIntervalsmsLabel            matlab.ui.control.Label
        ThisdataLabel                   matlab.ui.control.Label
        RisePanel                       matlab.ui.container.Panel
        RiseTimeLabel                   matlab.ui.control.Label
        StartLabel                      matlab.ui.control.Label
        r_StartEditField                matlab.ui.control.NumericEditField
        r_EndLabel                      matlab.ui.control.Label
        r_EndEditField                  matlab.ui.control.NumericEditField
        SteadyPanel                     matlab.ui.container.Panel
        SteadyStateLabel                matlab.ui.control.Label
        s_StartLabel                    matlab.ui.control.Label
        s_StartEditField                matlab.ui.control.NumericEditField
        s_EndLabel                      matlab.ui.control.Label
        s_EndEditField                  matlab.ui.control.NumericEditField
        TailPanel                       matlab.ui.container.Panel
        TailCurrentLabel                matlab.ui.control.Label
        t_StartLabel                    matlab.ui.control.Label
        t_StartEditField                matlab.ui.control.NumericEditField
        t_EndLabel                      matlab.ui.control.Label
        t_EndEditField                  matlab.ui.control.NumericEditField
        ConductancePanel                matlab.ui.container.Panel
        ConductanceLabel                matlab.ui.control.Label
        TimepointLabel                  matlab.ui.control.Label
        PointValueEditField             matlab.ui.control.NumericEditField
        SaveintervalsforallplotsButton  matlab.ui.control.Button
        TimeScaleDropDownLabel          matlab.ui.control.Label
        TimeScaleDropDown               matlab.ui.control.DropDown
        BACKButton                      matlab.ui.control.Button
        PlotGraphButton                 matlab.ui.control.StateButton
        NEXTButton                      matlab.ui.control.Button
        ExcludeCheckBox                 matlab.ui.control.CheckBox
        HelpImage                       matlab.ui.control.Image
        ResetButton                     matlab.ui.control.Button
        SaveSettingsButton              matlab.ui.control.Button
        ErrorLabel                      matlab.ui.control.Label
        RunAnalysisButton               matlab.ui.control.StateButton
    end

% LE 11th December 2020
    
    properties (Access = private)
        add_lib = addpath(genpath(fullfile(pwd, 'lib/')));
        settingsFile=0;
        settingsPath=0;
        out_path=0; 
        data_path=0; 
        data_file=0;
        path;
        dataOut=0;
        ImportApp; % connection to ImportMAT.mlapp
        importSettings=[];
        lastPath;
        time_abbrev_map;
        time_scale_map;
        
        % All plots (initialiseData)
        data;
        titles_all;
        time_col;
        time_max;
        
        % Plotting to UIAxes
        nplot;
        paramDefaults;
        wellValues;
        start;
        
        % Graphed plots
        plots_graph;
        titles_suffix;
        plotsNum_graph;
        titles_graph;
        plots_param;

        % Analysed plots
        plots_analysis;
        plotsNum_analysis;
        titles_analysis;
        plots_param_analysis
        
        % Well buttons
        wells = {'Well_1', 'Well_2', 'Well_3', 'Well_4', 'Well_5', 'Well_6', 'Well_7', 'Well_8'};
    end

    methods (Access = private) 
        
        function loadState(app)        
            load(fullfile(app.settingsPath, app.settingsFile),'state');
            
            if app.loadParams(state.paramDefaults)
                app.ErrorLabel.Text = "";
                app.InitialEditField.Value = state.InitialEditField.Value;
                app.StepsEditField.Value = state.StepsEditField.Value;
                app.EndEditField.Value = state.EndEditField.Value;
                app.CurrentScaleDropDown.Value = state.DataScaleDropDown.Value;
                app.CalculateConductanceCheckBox.Value = state.ConductanceCalculationCheckBox.Value;
                app.eKmVEditField.Value = state.eKmVEditField.Value;
                app.SubEditField.Value = state.SubEditField.Value;
                app.SupEditField.Value = state.SupEditField.Value; 
                app.paramDefaults = state.paramDefaults;
                app.TimeScaleDropDown.Value = 'milliseconds';
                
                app.ClearAllButtonPushed;
                app.SettingsFileLabel.Text = app.settingsFile;
            end
        end
        
        function saveState(app)
            state.InitialEditField.Value = app.InitialEditField.Value;
            state.StepsEditField.Value = app.StepsEditField.Value;
            state.EndEditField.Value = app.EndEditField.Value;
            state.DataScaleDropDown.Value = app.CurrentScaleDropDown.Value;
            state.ConductanceCalculationCheckBox.Value = app.CalculateConductanceCheckBox.Value;
            state.eKmVEditField.Value = app.eKmVEditField.Value;
            state.SubEditField.Value = app.SubEditField.Value;
            state.SupEditField.Value = app.SupEditField.Value;
            state.paramDefaults = {app.r_StartEditField.Value, app.r_EndEditField.Value,...
                app.s_StartEditField.Value, app.s_EndEditField.Value, app.t_StartEditField.Value,...
                app.t_EndEditField.Value, app.PointValueEditField.Value, 0};

            app.SettingsFileLabel.Text = app.settingsFile;
            try
                save(fullfile(app.settingsPath, app.settingsFile),'state');
            catch ME
                if (strcmp(ME.identifier,'MATLAB:save:noParentDir'))
                    error("Cannot find 'lib' folder. FIX: check that files have been extracted from .zip file and that MATLAB is open to the correct Current Folder.");
                else % rethrow error if something else
                    rethrow(ME);
                end
            end
        end
        
        %% Imports data from data file
        function importData(app)
            if ~isequal(app.data_file,0)
                % Initialise input data
                [app.data, app.titles_all, app.time_col, app.time_max]...
                    = initialiseData(app.data_path, app.dataOut, app.StepsEditField.Value);
                
                % Update time interval label + limits
                app.ThisdataLabel.Text = strcat("This data: 0 - ", num2str(app.time_max));
                app.r_StartEditField.Limits = [0 app.time_max];
                app.r_EndEditField.Limits = [0 app.time_max];
                app.s_StartEditField.Limits = [0 app.time_max];
                app.s_EndEditField.Limits = [0 app.time_max];
                app.t_StartEditField.Limits = [0 app.time_max];
                app.t_EndEditField.Limits = [0 app.time_max];
                app.PointValueEditField.Limits = [0 app.time_max];
                app.loadParams(app.paramDefaults);
            else
                app.titles_all = app.wells; % all well buttons to be enabled
            end
            
            % Disable well buttons not in data 
            hideLabel = uilabel("Text", "", "BackgroundColor", [0.65,0.65,0.65], "Enable", "off", "Position", [1,1,1270,780], "Parent", app.WellsTab);
            updateLabel = uilabel("Text", "Updating Wells...", "FontSize", 50, "HorizontalAlignment", "center", "BackgroundColor", [1,1,1], "Position", [400,290,450,200], "Parent", app.WellsTab);
            
            for i = 1:size(app.wells,2)
                w = eval(['app.', app.wells{i}, 'Button']);
                if ~contains(app.titles_all, app.wells{i})
                    w.Enable = 'off';
                else
                    w.Enable = 'on';
                end
            end
            delete(hideLabel);
            delete(updateLabel);
        end
        
        %% Adds labels for axes and title
        function annotatePlot(app)
            % Update X and Y Labels
            app.UIAxes.XLabel.String = strcat('Time (', app.time_abbrev_map(app.TimeScaleDropDown.Value), ')');
            app.UIAxes.YLabel.String = strcat('Current (', app.CurrentScaleDropDown.Value, ')');
            % Update title
            app.UIAxes.Title.String = strcat("Figure ", num2str(app.nplot), " - ",...
                app.titles_graph{app.nplot});
        end
        
        %% Loads saved parameters into fields
        function success = loadParams(app, params)
            success = 1;
            try
                app.r_StartEditField.Value = params{1};
                app.r_EndEditField.Value = params{2};
                app.s_StartEditField.Value = params{3}; 
                app.s_EndEditField.Value = params{4}; 
                app.t_StartEditField.Value = params{5}; 
                app.t_EndEditField.Value = params{6};
                app.PointValueEditField.Value = params{7};
                app.ExcludeCheckBox.Value = params{8};
            catch ME % error thrown when trying to load params into fields
                if (strcmp(ME.identifier,'MATLAB:ui:NumericEditField:invalidValue'))
                    app.ErrorLabel.Text = strcat("ERROR: Start and end of time intervals in settings file '",...
                                            app.settingsFile, "' are not numbers within the specified limits.");
                    success = 0;
                    return
                else % rethrow error if something else
                    rethrow(ME);
                end
            end 
        end
        
        %% Gets the values of the well buttons (on/off)
        function getWellValues(app)
            for i = 1:size(app.wells,2)
                button = eval(['app.', app.wells{i}, 'Button']);
                app.wellValues(i) = button.Value;
            end
        end
        
        %% Gets plots for selected wells for graphing => plots_graph
        function getPlots(app)
            app.getWellValues;
            
            % Gather plots selected from data
            [app.plots_graph, app.titles_graph] = gatherPlots(app.data, app.wells,...
                app.wellValues, app.titles_all, app.StepsEditField.Value, app.CurrentScaleDropDown.Value);
            
            app.plotsNum_graph = size(app.plots_graph, 2);
            app.plots_param = cell(app.plotsNum_graph, 8);
            for i = 1:size(app.plots_param,1)
                app.plots_param(i, :) = app.paramDefaults;
            end
        end
        
        %% Removes excluded plots for analysis => plots_analysis
        function excludePlots(app)
            count = 1;
            app.plots_param_analysis = cell(0);
            app.plots_analysis = cell(0);
            app.titles_analysis = cell(0);
            for i = 1:app.plotsNum_graph
                if ~app.plots_param{i, 8}
                    app.plots_analysis{count} = app.plots_graph{i};
                    app.titles_analysis{count} = app.titles_graph{i};
                    app.plots_param_analysis(count, :) = app.plots_param(i, :);
                    count = count + 1;
                end
            end
            app.plotsNum_analysis = size(app.plots_analysis, 2);
        end
        
        %% Checks if has input needed to run + enables buttons, otherwise disables buttons
        function ready = readyToRun(app)
            app.getWellValues;
            if ~isequal(app.data_file, 0) && any(app.wellValues)
                app.PlotGraphButton.Enable = 'on';
                app.RunAnalysisButton.Enable = 'on';  
                ready = true;
            else
                app.PlotGraphButton.Enable = 'off';
                app.NEXTButton.Enable = 'off';
                app.BACKButton.Enable = 'off';
                app.ExcludeCheckBox.Enable = 'off';
                app.RunAnalysisButton.Enable = 'off';
                ready = false;
            end
        end
        
        function readyToImport(app)
            if ~isequal(app.data_file, 0)
                app.ImportDataButton.Enable = 'on';
            else
                app.ImportDataButton.Enable = 'off';
                app.getWellValues;
                if any(app.wellValues)
                    app.ClearAllButtonPushed;
                end
            end
        end
    end
    
    methods (Access = public)
        
        %% Runs importMAT App and captures input settings provided
        function importMatFile(app, cells, sweeps, iters, test_pulse)
            if isequal(cells, 0)
                app.dataOut = 0;
            else
                app.dataOut = import_MAT(app.data_file, cells, sweeps, iters, test_pulse);
                app.importSettings = [{find(cells)}, sweeps, iters, test_pulse];
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.settingsFile = 'DefaultSettings.mat';
            app.settingsPath = fullfile(pwd, 'lib');
            app.UIAxes.Title.Interpreter = 'none'; % allows "_" in Axes Title
            app.lastPath = fullfile(pwd, filesep);
            
            % Time units abbrev
            time = {'milliseconds', 'seconds'}; % data is orginally in ms
            time_abbrev = {'ms', 's'};
            time_mults = {1, 0.001};
            app.time_abbrev_map = containers.Map(time, time_abbrev);
            app.time_scale_map = containers.Map(time, time_mults);
            
            try % load DefaultSettings.mat
                app.loadState();
            catch % if no default settings have been saved yet, then save them to DefaultSettings.mat
                app.InitialEditField.Value = -20;
                app.StepsEditField.Value = 13;
                app.EndEditField.Value = -140;
                app.CurrentScaleDropDown.Value = 'micro';
                app.SubEditField.Value = 0.1;
                app.SupEditField.Value = 0.9;

                app.paramDefaults = {0, 0, 0, 0, 0, 0, 0, 0};
                app.loadParams(app.paramDefaults);
                app.saveState;
            end

        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            
            if isequal(selectedTab, app.AnalysisConfigurationTab) || isequal(selectedTab, app.WellsTab)
                app.RunAnalysisButton.Visible = 'off';
            else
                app.RunAnalysisButton.Visible = 'on';
            end
        end

        % Image clicked function: HelpImage
        function HelpImageClicked(app, event)
            open("PatchlinerAuto_2.0 User Guide.pdf");
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.data_file = 0;
            app.DataFileLabel.Text = '';
            app.DataPathLabel.Text = '';
            app.FilenameLabel.Visible = 'off';
            app.FileLocationLabel.Visible = 'off';
            app.ThisdataLabel.Text = "This data: 0 - Inf";
            app.ErrorLabel.Text = "";
            
           app.AnyWellButtonValueChanged;
            for i = 1:size(app.wells,2)
                allCol = eval(['app.', app.wells{i}, 'Button']);
                allCol.Enable = 'off';
                allCol.Value = 0;
            end
            app.loadState;
            app.readyToImport;
        end

        % Button pushed function: SaveSettingsButton
        function SaveSettingsButtonPushed(app, event)
            app.PatchlinerAutoUIFigure.Visible = 'off';
            [app.settingsFile, app.settingsPath] = uiputfile({'*.*',  'All Files (*.*)'},...
                'Choose Filename for Settings File', fullfile('lib', 'SavedSettings.mat'));
            
            if ~isequal(app.settingsFile,0) % user hasn't pressed cancel
                app.saveState;
            end
            app.PatchlinerAutoUIFigure.Visible = 'on';
            
        end

        % Button pushed function: BrowseDataFile
        function BrowseDataFileButtonPushed(app, event)
            app.PatchlinerAutoUIFigure.Visible = 'off';
            
            % Issue with MacOS Catalina where filtering isn't working
%             [app.data_file,app.path] = uigetfile({'*.mat', 'MAT-files (*.mat) (recommended)';... 
%                 '*.csv', 'Comma Separated Values (*.csv)'; '*',  'All Files (*.*)'}, 'Select Data File', app.lastPath);

            [app.data_file,app.path] = uigetfile({'*', 'All Files'}, 'Select Data File', app.lastPath);
            
            app.PatchlinerAutoUIFigure.Visible = 'on'; 
            
            if isequal(app.data_file,0) % user pressed cancel
                app.DataFileLabel.Text = '';
                app.DataPathLabel.Text = '';
                app.FilenameLabel.Visible = 'off';
                app.FileLocationLabel.Visible = 'off';
                    
            else
                try 
                    [~, ~, filetype] = fileparts(app.data_file);
                    if ~strcmp(filetype, '.mat') && ~strcmp(filetype, '.csv') % if not a valid filetype
                        error('MATLAB:ImportData:InvalidFileType',"ERROR: The file type " + filetype + " is invalid. Valid file types are '.mat' or '.csv'.");
                    else
                        app.lastPath = fullfile(app.path, filesep);
                        app.data_path = fullfile(app.path,app.data_file);
                        app.DataFileLabel.Text = app.data_file;
                        
                        % Wrapping data path over multiple lines
                        str = strsplit(app.path, filesep);
                        pathLabel = strjoin(str, filesep+" ");
                        wrapLabelText(app.DataPathLabel, pathLabel); % wraps the text in the textbox
                        
                        app.FilenameLabel.Visible = 'on';
                        app.FileLocationLabel.Visible = 'on';
                    end
                    
                catch ME
                    if strcmp(ME.identifier, 'MATLAB:ImportData:InvalidFileType')
                        app.ErrorLabel.Text = ME.message;
                        return
                    end
                end

            end
            app.readyToImport;
        end

        % Value changed function: CalculateConductanceCheckBox
        function CalculateConductanceCheckBoxValueChanged(app, event)
            value = app.CalculateConductanceCheckBox.Value;
            
            if isequal(value, 0)
                app.ConductanceLabel.Enable = "off";
                app.PointValueEditField.Enable = "off";
                app.TimepointLabel.Enable = "off";
                app.eKmVEditField.Enable = "off";
                app.eKmVLabel.Enable = "off";
            else
                app.ConductanceLabel.Enable = "on";
                app.PointValueEditField.Enable = "on";
                app.TimepointLabel.Enable = "on";
                app.eKmVEditField.Enable = "on";
                app.eKmVLabel.Enable = "on";
            end
            cla(app.UIAxes);
        end

        % Button pushed function: LoadSettingsFile
        function LoadSettingsFileButtonPushed(app, event)
            app.PatchlinerAutoUIFigure.Visible = 'off';
            hideLabel = uilabel("Text", "", "BackgroundColor", [0.65,0.65,0.65], "Enable", "off", "Position", [1,1,1270,780], "Parent", app.PatchlinerAutoUIFigure);
            loadLabel = uilabel("Text", "Loading Settings...", "FontSize", 50, "HorizontalAlignment", "center", "BackgroundColor", [1,1,1], "Position", [400,290,450,200], "Parent", app.PatchlinerAutoUIFigure);
            
            [app.settingsFile, app.settingsPath] = uigetfile({'*.mat', 'MAT-Files (*.mat)'; '*.*','All Files (*.*)'},...
                'Select Settings File', fullfile('lib', app.settingsFile));
            app.PatchlinerAutoUIFigure.Visible = 'on';
            
            try
                if ~isequal(app.settingsFile,0) % user hasn't pressed cancel
                    app.loadState
                end
                delete(hideLabel);
                delete(loadLabel);
            catch ME
                delete(hideLabel);
                delete(loadLabel);
                rethrow(ME);
            end
            
        end

        % Value changed function: ImportDataButton
        function ImportDataButtonValueChanged(app, event)
            hideLabel = uilabel("Text", "", "BackgroundColor", [0.65,0.65,0.65], "Enable", "off", "Position", [1,1,1270,780], "Parent", app.PatchlinerAutoUIFigure);
            importLabel = uilabel("Text", "Importing Data...", "FontSize", 50, "HorizontalAlignment", "center", "BackgroundColor", [1,1,1], "Position", [400,290,450,200], "Parent", app.PatchlinerAutoUIFigure);
            app.dataOut = 0;
            
            try 
                [~, ~, filetype] = fileparts(app.data_file);
                if strcmp(filetype, ".mat") % if .mat then import file
                    addpath(app.path); % add source folder to path
                    app.ImportApp = ImportMAT(app, app.data_file);
                    waitfor(app.ImportApp);
                    if isequal(app.dataOut, 0) % ImportApp was exited
                        error('MATLAB:ImportData:ImportAppExited','ERROR: ImportApp was exited.');
                    end
                else
                    app.importSettings = [];
                end
                
                app.importData;
                app.ClearAllButtonPushed;
                delete(hideLabel);
                delete(importLabel);
            catch ME
                delete(hideLabel);
                delete(importLabel);
                app.ClearAllButtonPushed;
                if ~strcmp(ME.identifier, 'MATLAB:ImportData:ImportAppExited')
                    rethrow(ME);
                end
            end
        end

        % Value changed function: Well_1Button, Well_2Button, 
        % Well_3Button, Well_4Button, Well_5Button, Well_6Button, 
        % Well_7Button, Well_8Button
        function AnyWellButtonValueChanged(app, event)
            cla(app.UIAxes);
            app.UIAxes.XLabel.String = 'X';
            app.UIAxes.YLabel.String = 'Y';
            app.UIAxes.Title.String = 'Title';
            app.loadParams(app.paramDefaults);
            
            app.start = true;
            app.NEXTButton.Enable = 'off';
            app.BACKButton.Enable = 'off';
            app.readyToRun;
        end

        % Button pushed function: SelectAllButton
        function SelectAllButtonPushed(app, event)
            for i = 1:size(app.wells,2)
                allCol = eval(['app.', app.wells{i}, 'Button']);
                if strcmp(allCol.Enable, 'on')
                    allCol.Value = 1;
                end
            end
            app.AnyWellButtonValueChanged;
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            for i = 1:size(app.wells,2)
                allCol = eval(['app.', app.wells{i}, 'Button']);
                allCol.Value = 0;
            end
            app.AnyWellButtonValueChanged;
            
        end

        % Button pushed function: SaveintervalsforallplotsButton
        function SaveintervalsforallplotsButtonPushed(app, event)
            app.paramDefaults = {app.r_StartEditField.Value, app.r_EndEditField.Value,...
                app.s_StartEditField.Value, app.s_EndEditField.Value, app.t_StartEditField.Value,...
                app.t_EndEditField.Value, app.PointValueEditField.Value, 0};
            for i = 1:size(app.plots_param,1)
                app.plots_param(i, 1:7) = app.paramDefaults(1:7);
            end
            if app.readyToRun
                app.PlotGraphButtonValueChanged;
            end
        end

        % Value changed function: TimeScaleDropDown
        function TimeScaleDropDownValueChanged(app, event)
            app.PlotGraphButtonValueChanged;
            
        end

        % Value changed function: PlotGraphButton
        function PlotGraphButtonValueChanged(app, event)
            if app.start
                app.nplot = 1;
                app.getPlots;
                app.start = false;
                app.ExcludeCheckBox.Enable = 'on';
            end
            
            % Clear errors + axes then plot data + bounds
            app.ErrorLabel.Text = "";
            cla(app.UIAxes);
            
            % Scale for time column
            time_scale = app.time_scale_map(app.TimeScaleDropDown.Value);
            
            plot_data(app.plots_graph{app.nplot}, app.time_col, time_scale, app.UIAxes);           
            hold(app.UIAxes,'on');
            
            try
                plot_bounds(app.plots_graph{app.nplot}, app.time_col, time_scale, app.r_StartEditField.Value,...
                    app.r_EndEditField.Value, app.s_StartEditField.Value, app.s_EndEditField.Value,...
                    app.t_StartEditField.Value, app.t_EndEditField.Value, app.PointValueEditField.Value, app.CalculateConductanceCheckBox.Value, app.UIAxes);
            catch ME % error thrown when trying to plot bounds
                if (strcmp(ME.identifier,'MATLAB:hg:dt_conv:Matrix_to_matlabRect:Expected4ElementVector'))
                    app.ErrorLabel.Text = "ERROR: Start times of time intervals must be before end times.";
                    return
                elseif (strcmp(ME.identifier,'MATLAB:findTimes:startTimeNotFound'))
                    app.ErrorLabel.Text = ME.message;
                    return
                elseif (strcmp(ME.identifier,'MATLAB:findTimes:endTimeNotFound'))
                    app.ErrorLabel.Text = ME.message;
                    return
                else % rethrow error if something else
                    rethrow(ME);
                end
            end 
            app.annotatePlot;
            
            if app.nplot == app.plotsNum_graph % if last plot
                app.NEXTButton.Enable = 'off';
            else
                app.NEXTButton.Enable = 'on';
            end
            
            if app.nplot == 1 % if first plot
                app.BACKButton.Enable = 'off';
            else
                app.BACKButton.Enable = 'on';
            end

        end

        % Button pushed function: NEXTButton
        function NEXTButtonPushed(app, event)
            if app.nplot ~= app.plotsNum_graph % if not last plot
                % Saving parameters for previous plot
                app.plots_param(app.nplot, :) = {app.r_StartEditField.Value, app.r_EndEditField.Value,...
                    app.s_StartEditField.Value, app.s_EndEditField.Value, app.t_StartEditField.Value,...
                    app.t_EndEditField.Value, app.PointValueEditField.Value, app.ExcludeCheckBox.Value};
                
                % Loading parameters for next plot
                app.nplot = app.nplot + 1;
                app.loadParams(app.plots_param(app.nplot, :)); 
    
                app.PlotGraphButtonValueChanged;
            end
        end

        % Button pushed function: BACKButton
        function BACKButtonPushed(app, event)
            if app.nplot ~= 1 % if not first plot
                % Saving parameters for previous plot
                app.plots_param(app.nplot, :) = {app.r_StartEditField.Value, app.r_EndEditField.Value,...
                    app.s_StartEditField.Value, app.s_EndEditField.Value, app.t_StartEditField.Value,...
                    app.t_EndEditField.Value, app.PointValueEditField.Value, app.ExcludeCheckBox.Value};   
                
                % Loading parameters for next plot
                app.nplot = app.nplot - 1;
                app.loadParams(app.plots_param(app.nplot, :));
                
                app.PlotGraphButtonValueChanged;
            end
        end

        % Window key press function: PatchlinerAutoUIFigure
        function PatchlinerAutoUIFigureWindowKeyPress(app, event)
            key = event.Key;
            if strcmp(app.TabGroup.SelectedTab.Title, 'Graph')
                if strcmp(key, 'rightarrow') && strcmp(app.NEXTButton.Enable, 'on')
                    app.NEXTButtonPushed;
                elseif strcmp(key, 'leftarrow') && strcmp(app.BACKButton.Enable, 'on')
                    app.BACKButtonPushed;
                elseif strcmp(key, 'downarrow') && strcmp(app.PlotGraphButton.Enable, 'on')
                    app.PlotGraphButtonValueChanged;
                end
            end
        end

        % Value changed function: RunAnalysisButton
        function RunAnalysisButtonPushed(app, event)
            try
                app.PatchlinerAutoUIFigure.Visible = 'off';
                hideLabel = uilabel("Text", "", "BackgroundColor", [0.65,0.65,0.65], "Enable", "off", "Position", [1,1,1270,780], "Parent", app.PatchlinerAutoUIFigure);
                analysisLabel = uilabel("Text", "Running Analysis...", "FontSize", 50, "HorizontalAlignment", "center", "BackgroundColor", [1,1,1], "Position", [400,290,450,200], "Parent", app.PatchlinerAutoUIFigure);
                
                app.PlotGraphButtonValueChanged; % needed if wells are changed and plots not graphed...
                                                    % before analysis
                app.plots_param(app.nplot, :) = {app.r_StartEditField.Value, app.r_EndEditField.Value,...
                    app.s_StartEditField.Value, app.s_EndEditField.Value, app.t_StartEditField.Value,...
                    app.t_EndEditField.Value, app.PointValueEditField.Value, app.ExcludeCheckBox.Value};
                    
                app.excludePlots;
                time_scale = app.time_scale_map(app.TimeScaleDropDown.Value); % Scale for time column
                
                if app.plotsNum_analysis ~= 0 % all plots not excluded
                    app.out_path = uigetdir('', 'Select Output Folder');
                    app.PatchlinerAutoUIFigure.Visible = 'on';
                    
                    if ~isequal(app.out_path,0) %user hasn't pressed cancel
                        % Create new timestamped output folder
                        folder_name = strcat(datestr(now,'yyyy-mm-dd HH.MM.SS PM'), '  PatchlinerAuto');
                        mkdir(app.out_path, folder_name);
                        
                        folder_path = fullfile(app.out_path, folder_name);
                        settings_path = fullfile(app.settingsPath, app.settingsFile);
                            
                        % Calculate + save output files
                        output_files(app.plots_analysis, app.plotsNum_analysis, app.plots_param_analysis,...
                            app.CalculateConductanceCheckBox.Value, app.eKmVEditField.Value, folder_path, app.data_path, settings_path,...
                            app.time_col, string(app.titles_analysis),string(app.titles_graph), app.SubEditField.Value,...
                            app.SupEditField.Value, app.InitialEditField.Value, app.StepsEditField.Value,...
                            app.EndEditField.Value, app.CurrentScaleDropDown.Value, app.importSettings);
                        
                        % if data was imported from MATLAB file (.mat), output raw data to .csv  
                        if ~isequal(app.dataOut, 0)
                            [~, file, filetype] = fileparts(app.data_path);
                            if isequal(filetype, ".mat")
                                writematrix(app.dataOut, fullfile(folder_path, [file '_imported.csv']));
                            end
                        end
                        
                        % Save figures
                        save_figures(folder_path, app.plots_analysis, app.plotsNum_analysis, app.titles_analysis,...
                            app.time_col, time_scale, app.time_abbrev_map(app.TimeScaleDropDown.Value), app.CurrentScaleDropDown.Value);
                        
                    end
                else
                    app.ErrorLabel.Text = "ERROR: All plots have been excluded.";
                end
                delete(hideLabel);
                delete(analysisLabel);
                app.PatchlinerAutoUIFigure.Visible = 'on';
            catch ME
                delete(hideLabel);
                delete(analysisLabel);
                app.PatchlinerAutoUIFigure.Visible = 'on';
                rethrow(ME);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PatchlinerAutoUIFigure = uifigure('Visible', 'off');
            app.PatchlinerAutoUIFigure.Name = 'PatchlinerAuto';
            app.PatchlinerAutoUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @PatchlinerAutoUIFigureWindowKeyPress, true);
            app.TabGroup = uitabgroup(app.PatchlinerAutoUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.AnalysisConfigurationTab = uitab(app.TabGroup);
            app.AnalysisConfigurationTab.Title = 'Analysis Configuration';
            app.ImportDataPanel = uipanel(app.AnalysisConfigurationTab);
            app.ImportDataPanel.Title = 'Import Data';
            app.DataFilerequiredLabel = uilabel(app.ImportDataPanel);
            app.DataFilerequiredLabel.Text = 'Data File (required)';
            app.BrowseDataFile = uibutton(app.ImportDataPanel, 'push');
            app.BrowseDataFile.ButtonPushedFcn = createCallbackFcn(app, @BrowseDataFileButtonPushed, true);
            app.BrowseDataFile.Text = 'Browse';
            app.DataFileLabel = uilabel(app.ImportDataPanel);
            app.DataFileLabel.Text = '';
            app.FilenameLabel = uilabel(app.ImportDataPanel);
            app.FilenameLabel.Visible = 'off';
            app.FilenameLabel.Text = 'Filename:';
            app.FileLocationLabel = uilabel(app.ImportDataPanel);
            app.FileLocationLabel.Visible = 'off';
            app.FileLocationLabel.Text = 'File Location:';
            app.DataPathLabel = uilabel(app.ImportDataPanel);
            app.DataPathLabel.Text = '';
            app.VoltageParametersRequiredPanel = uipanel(app.AnalysisConfigurationTab);
            app.VoltageParametersRequiredPanel.Title = 'Voltage Parameters (Required)';
            app.InitialEditFieldLabel = uilabel(app.VoltageParametersRequiredPanel);
            app.InitialEditFieldLabel.Text = 'Initial (mV)';
            app.InitialEditField = uieditfield(app.VoltageParametersRequiredPanel, 'numeric');
            app.InitialEditField.LowerLimitInclusive = 'off';
            app.InitialEditField.ValueDisplayFormat = '%11.7g';
            app.InitialEditField.Value = -130;
            app.EndEditFieldLabel = uilabel(app.VoltageParametersRequiredPanel);
            app.EndEditFieldLabel.Text = 'End (mV)';
            app.EndEditField = uieditfield(app.VoltageParametersRequiredPanel, 'numeric');
            app.EndEditField.ValueDisplayFormat = '%11.7g';
            app.EndEditField.Value = -45;
            app.NumberofvoltagestepsLabel = uilabel(app.VoltageParametersRequiredPanel);
            app.NumberofvoltagestepsLabel.Text = 'Number of voltage steps';
            app.StepsEditField = uieditfield(app.VoltageParametersRequiredPanel, 'numeric');
            app.StepsEditField.Limits = [0 Inf];
            app.StepsEditField.RoundFractionalValues = 'on';
            app.StepsEditField.ValueDisplayFormat = '%.0f';
            app.StepsEditField.Value = 10;
            app.sweepspercellLabel = uilabel(app.VoltageParametersRequiredPanel);
            app.sweepspercellLabel.Text = '(sweeps per cell)';
            app.ParametersPanel_1 = uipanel(app.AnalysisConfigurationTab);
            app.CurrentScaleDropDownLabel = uilabel(app.ParametersPanel_1);
            app.CurrentScaleDropDownLabel.Text = 'Current Scale';
            app.CurrentScaleDropDown = uidropdown(app.ParametersPanel_1);
            app.CurrentScaleDropDown.Items = {'micro', 'nano', 'pico'};
            app.CurrentScaleDropDown.Value = 'micro';
            app.CalculateConductanceCheckBox = uicheckbox(app.ParametersPanel_1);
            app.CalculateConductanceCheckBox.ValueChangedFcn = createCallbackFcn(app, @CalculateConductanceCheckBoxValueChanged, true);
            app.CalculateConductanceCheckBox.Text = ' Calculate Conductance';
            app.CalculateConductanceCheckBox.Value = true;
            app.eKmVLabel = uilabel(app.ParametersPanel_1);
            app.eKmVLabel.Text = 'eK  (mV)';
            app.eKmVEditField = uieditfield(app.ParametersPanel_1, 'numeric');
            app.eKmVEditField.ValueDisplayFormat = '%11.7g';
            app.eKmVEditField.Value = -90;
            app.ParametersPanel_2 = uipanel(app.AnalysisConfigurationTab);
            app.RiseTimePercentagesLabel = uilabel(app.ParametersPanel_2);
            app.RiseTimePercentagesLabel.Text = ' Rise Time Percentages (eg. 0.1, 0.9):';
            app.SubLabel = uilabel(app.ParametersPanel_2);
            app.SubLabel.Text = 'Sub';
            app.SubEditField = uieditfield(app.ParametersPanel_2, 'numeric');
            app.SubEditField.Limits = [0 1];
            app.SubEditField.ValueDisplayFormat = '%11.7g';
            app.SubEditField.Value = 0.1;
            app.SupLabel = uilabel(app.ParametersPanel_2);
            app.SupLabel.Text = 'Sup';
            app.SupEditField = uieditfield(app.ParametersPanel_2, 'numeric');
            app.SupEditField.Limits = [0 1];
            app.SupEditField.ValueDisplayFormat = '%11.7g';
            app.SupEditField.Value = 0.9;
            app.LoadSettingsPanel = uipanel(app.AnalysisConfigurationTab);
            app.LoadSettingsPanel.Title = 'Load Settings from File (Optional)';
            app.LoadSettingsFile = uibutton(app.LoadSettingsPanel, 'push');
            app.LoadSettingsFile.ButtonPushedFcn = createCallbackFcn(app, @LoadSettingsFileButtonPushed, true);
            app.LoadSettingsFile.Text = 'Browse and Load';
            app.SettingsFileLabel = uilabel(app.LoadSettingsPanel);
            app.SettingsFileLabel.Text = '';
            app.CurrentSettingsFileLabel = uilabel(app.LoadSettingsPanel);
            app.CurrentSettingsFileLabel.Text = 'Current Settings File:';
            app.ImportDataButton = uibutton(app.AnalysisConfigurationTab, 'state');
            app.ImportDataButton.ValueChangedFcn = createCallbackFcn(app, @ImportDataButtonValueChanged, true);
            app.ImportDataButton.Enable = 'off';
            app.ImportDataButton.Text = 'Import Data';
            app.WellsTab = uitab(app.TabGroup);
            app.WellsTab.Title = 'Wells';
            app.WellsPanel = uipanel(app.WellsTab);
            app.Well_1Button = uibutton(app.WellsPanel, 'state');
            app.Well_1Button.ValueChangedFcn = createCallbackFcn(app, @AnyWellButtonValueChanged, true);
            app.Well_1Button.Enable = 'off';
            app.Well_1Button.Text = '1';
            app.Well_2Button = uibutton(app.WellsPanel, 'state');
            app.Well_2Button.ValueChangedFcn = createCallbackFcn(app, @AnyWellButtonValueChanged, true);
            app.Well_2Button.Enable = 'off';
            app.Well_2Button.Text = '2';
            app.Well_3Button = uibutton(app.WellsPanel, 'state');
            app.Well_3Button.ValueChangedFcn = createCallbackFcn(app, @AnyWellButtonValueChanged, true);
            app.Well_3Button.Enable = 'off';
            app.Well_3Button.Text = '3';
            app.Well_4Button = uibutton(app.WellsPanel, 'state');
            app.Well_4Button.ValueChangedFcn = createCallbackFcn(app, @AnyWellButtonValueChanged, true);
            app.Well_4Button.Enable = 'off';
            app.Well_4Button.Text = '4';
            app.Well_5Button = uibutton(app.WellsPanel, 'state');
            app.Well_5Button.ValueChangedFcn = createCallbackFcn(app, @AnyWellButtonValueChanged, true);
            app.Well_5Button.Enable = 'off';
            app.Well_5Button.Text = '5';
            app.Well_6Button = uibutton(app.WellsPanel, 'state');
            app.Well_6Button.ValueChangedFcn = createCallbackFcn(app, @AnyWellButtonValueChanged, true);
            app.Well_6Button.Enable = 'off';
            app.Well_6Button.Text = '6';
            app.Well_7Button = uibutton(app.WellsPanel, 'state');
            app.Well_7Button.ValueChangedFcn = createCallbackFcn(app, @AnyWellButtonValueChanged, true);
            app.Well_7Button.Enable = 'off';
            app.Well_7Button.Text = '7';
            app.Well_8Button = uibutton(app.WellsPanel, 'state');
            app.Well_8Button.ValueChangedFcn = createCallbackFcn(app, @AnyWellButtonValueChanged, true);
            app.Well_8Button.Enable = 'off';
            app.Well_8Button.Text = '8';
            app.ClearAllButton = uibutton(app.WellsPanel, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
            app.SelectAllButton = uibutton(app.WellsPanel, 'push');
            app.SelectAllButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAllButtonPushed, true);
            app.SelectAllButton.Text = 'Select All';
            app.GraphTab = uitab(app.TabGroup);
            app.GraphTab.Title = 'Graph';
            app.UIAxes = uiaxes(app.GraphTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.TimeIntervalsmsLabel = uilabel(app.GraphTab);
            app.TimeIntervalsmsLabel.Text = 'Time Intervals (ms)';
            app.ThisdataLabel = uilabel(app.GraphTab);
            app.ThisdataLabel.Text = 'This data: 0 - Inf';
            app.RisePanel = uipanel(app.GraphTab);
            app.RiseTimeLabel = uilabel(app.RisePanel);
            app.RiseTimeLabel.Text = 'Rise Time';
            app.StartLabel = uilabel(app.RisePanel);
            app.StartLabel.Text = 'Start';
            app.r_StartEditField = uieditfield(app.RisePanel, 'numeric');
            app.r_StartEditField.Limits = [0 Inf];
            app.r_StartEditField.ValueDisplayFormat = '%11g';
            app.r_StartEditField.Value = 4000;
            app.r_EndLabel = uilabel(app.RisePanel);
            app.r_EndLabel.Text = 'End';
            app.r_EndEditField = uieditfield(app.RisePanel, 'numeric');
            app.r_EndEditField.Limits = [0 Inf];
            app.r_EndEditField.ValueDisplayFormat = '%11g';
            app.r_EndEditField.Value = 14000;
            app.SteadyPanel = uipanel(app.GraphTab);
            app.SteadyStateLabel = uilabel(app.SteadyPanel);
            app.SteadyStateLabel.Text = 'Steady State Current Amplitude';
            app.s_StartLabel = uilabel(app.SteadyPanel);
            app.s_StartLabel.Text = 'Start';
            app.s_StartEditField = uieditfield(app.SteadyPanel, 'numeric');
            app.s_StartEditField.Limits = [0 Inf];
            app.s_StartEditField.ValueDisplayFormat = '%11g';
            app.s_StartEditField.Value = 4000;
            app.s_EndLabel = uilabel(app.SteadyPanel);
            app.s_EndLabel.Text = 'End';
            app.s_EndEditField = uieditfield(app.SteadyPanel, 'numeric');
            app.s_EndEditField.Limits = [0 Inf];
            app.s_EndEditField.ValueDisplayFormat = '%11g';
            app.s_EndEditField.Value = 13800;
            app.TailPanel = uipanel(app.GraphTab);
            app.TailCurrentLabel = uilabel(app.TailPanel);
            app.TailCurrentLabel.Text = 'Tail Current';
            app.t_StartLabel = uilabel(app.TailPanel);
            app.t_StartLabel.Text = 'Start';
            app.t_StartEditField = uieditfield(app.TailPanel, 'numeric');
            app.t_StartEditField.Limits = [0 Inf];
            app.t_StartEditField.ValueDisplayFormat = '%11g';
            app.t_StartEditField.Value = 14000;
            app.t_EndLabel = uilabel(app.TailPanel);
            app.t_EndLabel.Text = 'End';
            app.t_EndEditField = uieditfield(app.TailPanel, 'numeric');
            app.t_EndEditField.Limits = [0 Inf];
            app.t_EndEditField.ValueDisplayFormat = '%11g';
            app.t_EndEditField.Value = 14100;
            app.ConductancePanel = uipanel(app.GraphTab);
            app.ConductanceLabel = uilabel(app.ConductancePanel);
            app.ConductanceLabel.Text = 'Conductance';
            app.TimepointLabel = uilabel(app.ConductancePanel);
            app.TimepointLabel.Text = 'Time point';
            app.PointValueEditField = uieditfield(app.ConductancePanel, 'numeric');
            app.PointValueEditField.Limits = [0 Inf];
            app.PointValueEditField.ValueDisplayFormat = '%11g';
            app.PointValueEditField.Value = 100;
            app.SaveintervalsforallplotsButton = uibutton(app.GraphTab, 'push');
            app.SaveintervalsforallplotsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveintervalsforallplotsButtonPushed, true);
            app.SaveintervalsforallplotsButton.Text = 'Save intervals for all plots';
            app.TimeScaleDropDownLabel = uilabel(app.GraphTab);
            app.TimeScaleDropDownLabel.Text = 'Time Scale';
            app.TimeScaleDropDown = uidropdown(app.GraphTab);
            app.TimeScaleDropDown.Items = {'milliseconds', 'seconds'};
            app.TimeScaleDropDown.ValueChangedFcn = createCallbackFcn(app, @TimeScaleDropDownValueChanged, true);
            app.TimeScaleDropDown.Value = 'milliseconds';
            app.BACKButton = uibutton(app.GraphTab, 'push');
            app.BACKButton.ButtonPushedFcn = createCallbackFcn(app, @BACKButtonPushed, true);
            app.BACKButton.Enable = 'off';
            app.BACKButton.Text = 'BACK';
            app.PlotGraphButton = uibutton(app.GraphTab, 'state');
            app.PlotGraphButton.ValueChangedFcn = createCallbackFcn(app, @PlotGraphButtonValueChanged, true);
            app.PlotGraphButton.Enable = 'off';
            app.PlotGraphButton.Text = 'Plot Graph';
            app.NEXTButton = uibutton(app.GraphTab, 'push');
            app.NEXTButton.ButtonPushedFcn = createCallbackFcn(app, @NEXTButtonPushed, true);
            app.NEXTButton.Enable = 'off';
            app.NEXTButton.Text = 'NEXT';
            app.ExcludeCheckBox = uicheckbox(app.GraphTab);
            app.ExcludeCheckBox.Enable = 'off';
            app.ExcludeCheckBox.Text = 'Exclude from Analysis';
            app.HelpImage = uiimage(app.PatchlinerAutoUIFigure);
            app.HelpImage.ImageClickedFcn = createCallbackFcn(app, @HelpImageClicked, true);
            app.HelpImage.ImageSource = 'help_icon_dkgrey.png';
            app.ResetButton = uibutton(app.PatchlinerAutoUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SaveSettingsButton = uibutton(app.PatchlinerAutoUIFigure, 'push');
            app.SaveSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSettingsButtonPushed, true);
            app.SaveSettingsButton.Text = 'Save Settings';
            app.ErrorLabel = uilabel(app.PatchlinerAutoUIFigure);
            app.ErrorLabel.Text = '';
            app.RunAnalysisButton = uibutton(app.PatchlinerAutoUIFigure, 'state');
            app.RunAnalysisButton.ValueChangedFcn = createCallbackFcn(app, @RunAnalysisButtonPushed, true);
            app.RunAnalysisButton.Enable = 'off';
            app.RunAnalysisButton.Visible = 'off';
            app.RunAnalysisButton.Text = 'Run Analysis';
            % Show the figure after all components are created
            app.PatchlinerAutoUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PatchlinerAuto

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PatchlinerAutoUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PatchlinerAutoUIFigure)
        end
    end
end