classdef ImportMAT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImportmatFileUIFigure  matlab.ui.Figure
        Panel                  matlab.ui.container.Panel
        Panel_2                matlab.ui.container.Panel
        FilenameLabel          matlab.ui.control.Label
        dataInputLabel         matlab.ui.control.Label
        Panel_3                matlab.ui.container.Panel
        SweepsperwellLabel     matlab.ui.control.Label
        SweepsEditField        matlab.ui.control.NumericEditField
        AppliedpharmocologyrunsitersLabel  matlab.ui.control.Label
        ItersEditField         matlab.ui.control.NumericEditField
        CheckBox_1             matlab.ui.control.CheckBox
        CheckBox_2             matlab.ui.control.CheckBox
        CheckBox_3             matlab.ui.control.CheckBox
        CheckBox_4             matlab.ui.control.CheckBox
        CheckBox_5             matlab.ui.control.CheckBox
        CheckBox_6             matlab.ui.control.CheckBox
        CheckBox_7             matlab.ui.control.CheckBox
        CheckBox_8             matlab.ui.control.CheckBox
        WellsrecordedLabel     matlab.ui.control.Label
        TestPulseCheckbox      matlab.ui.control.CheckBox
        TestpulseLabel         matlab.ui.control.Label
        ImportButton           matlab.ui.control.StateButton
        HelpImage              matlab.ui.control.Image
    end

    % LE 11th December 2020
    
    properties (Access = private)
        filename; % Input from main app
        CallingApp; % Main app object (PatchlinerAuto)
        cells;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, PatchlinerAuto, filename)
            % Store main app object
            app.CallingApp = PatchlinerAuto;

            % Process sz and c inputs
            app.filename = filename;
            app.dataInputLabel.Text = app.filename;
        end

        % Value changed function: ImportButton
        function ImportButtonValueChanged(app, event)
            % Call main app's public function
            importMatFile(app.CallingApp, app.cells, app.SweepsEditField.Value, app.ItersEditField.Value, app.TestPulseCheckbox.Value);
            
            % Close window
            delete(app);
        end

        % Value changed function: CheckBox_1, CheckBox_2, 
        % CheckBox_3, CheckBox_4, CheckBox_5, CheckBox_6, 
        % CheckBox_7, CheckBox_8
        function AnyCheckBoxValueChanged(app, event)
            app.cells = [app.CheckBox_1.Value, app.CheckBox_2.Value, app.CheckBox_3.Value, app.CheckBox_4.Value, ...
                app.CheckBox_5.Value, app.CheckBox_6.Value, app.CheckBox_7.Value, app.CheckBox_8.Value];
            
            if any(app.cells)
                app.ImportButton.Enable = "on";
            else
                app.ImportButton.Enable = "off";
            end
            
        end

        % Close request function: ImportmatFileUIFigure
        function ImportmatFileUIFigureCloseRequest(app, event)
            
            try 
                % Call main app's public function
                importMatFile(app.CallingApp, 0, 0, 0, 0);
                
            catch ME  % ImportApp run independent of main app then exited
                warning('ImportApp was run independent of PatchlinerAuto and then exited.');
            end
            
            % Close window
            delete(app)
        end

        % Image clicked function: HelpImage
        function HelpImageClicked(app, event)
            open("PatchlinerAuto_2.0 User Guide.pdf");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImportmatFileUIFigure = uifigure('Visible', 'off');
            app.ImportmatFileUIFigure.Name = 'Import .mat File';
            app.ImportmatFileUIFigure.CloseRequestFcn = createCallbackFcn(app, @ImportmatFileUIFigureCloseRequest, true);
            app.Panel = uipanel(app.ImportmatFileUIFigure);
            app.Panel_2 = uipanel(app.Panel);
            app.FilenameLabel = uilabel(app.Panel_2);
            app.FilenameLabel.Text = 'Filename: ';
            app.dataInputLabel = uilabel(app.Panel_2);
            app.dataInputLabel.Text = '';
            app.Panel_3 = uipanel(app.Panel);
            app.SweepsperwellLabel = uilabel(app.Panel_3);
            app.SweepsperwellLabel.Text = 'Sweeps per well';
            app.SweepsEditField = uieditfield(app.Panel_3, 'numeric');
            app.SweepsEditField.LowerLimitInclusive = 'off';
            app.SweepsEditField.Limits = [0 Inf];
            app.SweepsEditField.RoundFractionalValues = 'on';
            app.SweepsEditField.ValueDisplayFormat = '%.0f';
            app.SweepsEditField.Value = 1;
            app.AppliedpharmocologyrunsitersLabel = uilabel(app.Panel_3);
            app.AppliedpharmocologyrunsitersLabel.Text = 'Applied pharmocology runs (iters)';
            app.ItersEditField = uieditfield(app.Panel_3, 'numeric');
            app.ItersEditField.Limits = [0 Inf];
            app.ItersEditField.RoundFractionalValues = 'on';
            app.ItersEditField.ValueDisplayFormat = '%.0f';
            app.ItersEditField.Value = 1;
            app.CheckBox_1 = uicheckbox(app.Panel_3);
            app.CheckBox_1.ValueChangedFcn = createCallbackFcn(app, @AnyCheckBoxValueChanged, true);
            app.CheckBox_1.Text = ' 1';
            app.CheckBox_2 = uicheckbox(app.Panel_3);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @AnyCheckBoxValueChanged, true);
            app.CheckBox_2.Text = ' 2';
            app.CheckBox_3 = uicheckbox(app.Panel_3);
            app.CheckBox_3.ValueChangedFcn = createCallbackFcn(app, @AnyCheckBoxValueChanged, true);
            app.CheckBox_3.Text = ' 3';
            app.CheckBox_4 = uicheckbox(app.Panel_3);
            app.CheckBox_4.ValueChangedFcn = createCallbackFcn(app, @AnyCheckBoxValueChanged, true);
            app.CheckBox_4.Text = ' 4';
            app.CheckBox_5 = uicheckbox(app.Panel_3);
            app.CheckBox_5.ValueChangedFcn = createCallbackFcn(app, @AnyCheckBoxValueChanged, true);
            app.CheckBox_5.Text = ' 5';
            app.CheckBox_6 = uicheckbox(app.Panel_3);
            app.CheckBox_6.ValueChangedFcn = createCallbackFcn(app, @AnyCheckBoxValueChanged, true);
            app.CheckBox_6.Text = ' 6';
            app.CheckBox_7 = uicheckbox(app.Panel_3);
            app.CheckBox_7.ValueChangedFcn = createCallbackFcn(app, @AnyCheckBoxValueChanged, true);
            app.CheckBox_7.Text = ' 7';
            app.CheckBox_8 = uicheckbox(app.Panel_3);
            app.CheckBox_8.ValueChangedFcn = createCallbackFcn(app, @AnyCheckBoxValueChanged, true);
            app.CheckBox_8.Text = ' 8';
            app.WellsrecordedLabel = uilabel(app.Panel_3);
            app.WellsrecordedLabel.Text = 'Wells recorded:';
            app.TestPulseCheckbox = uicheckbox(app.Panel_3);
            app.TestPulseCheckbox.Text = '';
            app.TestpulseLabel = uilabel(app.Panel_3);
            app.TestpulseLabel.Text = 'Test-pulse:';
            app.ImportButton = uibutton(app.ImportmatFileUIFigure, 'state');
            app.ImportButton.ValueChangedFcn = createCallbackFcn(app, @ImportButtonValueChanged, true);
            app.ImportButton.Enable = 'off';
            app.ImportButton.Text = 'Import';
            app.HelpImage = uiimage(app.ImportmatFileUIFigure);
            app.HelpImage.ImageClickedFcn = createCallbackFcn(app, @HelpImageClicked, true);
            app.HelpImage.ImageSource = 'help_icon_dkgrey.png';
            % Show the figure after all components are created
            app.ImportmatFileUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImportMAT(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImportmatFileUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImportmatFileUIFigure)
        end
    end
end